classdef EdgeDetection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Panel               matlab.ui.container.Panel
        ImageAxes           matlab.ui.control.UIAxes
        LoadButton          matlab.ui.control.Button
        ImagesLabel         matlab.ui.control.Label
        DropDown            matlab.ui.control.DropDown
        ShowButton          matlab.ui.control.Button
        EdgeDetectionLabel  matlab.ui.control.Label
        SaveButton          matlab.ui.control.Button
    end

    
    methods (Access = public)
        function updateimage(app,imagefile)
            
            if strcmp(imagefile,'11.png')
                im = imread('11.png', 3);
                imshow(im, 'parent', app.ImageAxes);
                setappdata(0,'value',im);
            else
                try
                    im  = imread(imagefile);
                    imshow(im, 'parent', app.ImageAxes);
                    setappdata(0,'value',im);
                    
                catch ME
                    % If problem reading image, display error message
                    uialert(app.UIFigure, ME.message, 'Image Error');
                    return;
                end
            end
            
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure image axes
            app.ImageAxes.Visible = 'off';
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');
            
            % Update the image and histograms
            updateimage(app, '01.png');
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            
            updateimage(app, app.DropDown.Value);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                fname = [p f];
                updateimage(app, fname);
            end
        end

        % Button pushed function: ShowButton
        function ShowButtonPushed(app, event)
            %% Nettoyage
            global G_bin;
            %% Définitions
            value=getappdata(0,'value');
            TYPE = 'int32';
            img = value;
            
            img_ycbcr = rgb2ycbcr(img);
            img_Y   = int32(img_ycbcr(:,:,1));
            H = numel(img_Y(:,1));
            W = numel(img_Y(1,:));
            
            
            img_f = img_Y;
            
            % Balayage de l'image pour filtrage
            G_y     = zeros(size(img_Y), TYPE);
            G_x     = zeros(size(img_Y), TYPE);
            G       = zeros(size(img_Y), TYPE);
            
            G_bin   = zeros(size(img_Y), TYPE);
            theta   = zeros(size(img_Y), TYPE);
            theta_p = zeros(size(img_Y), TYPE);
            
            for ind_y = 2 : H-1
                for ind_x = 2 : W-1
                    % Filtre de Sobel
                    % Application du filtre horozontal
                    G_x(ind_y, ind_x) = (...
                        +1*img_f(ind_y-1,ind_x-1) + 0*img_f(ind_y-1,ind_x) - 1*img_f(ind_y-1,ind_x+1) + ...
                        +2*img_f(ind_y  ,ind_x-1) - 0*img_f(ind_y  ,ind_x) - 2*img_f(ind_y  ,ind_x+1) + ...
                        +1*img_f(ind_y+1,ind_x-1) + 0*img_f(ind_y+1,ind_x) - 1*img_f(ind_y+1,ind_x+1) );
                    
                    % Application du filtre vertical
                    G_y(ind_y, ind_x) = (...
                        +1*img_f(ind_y-1,ind_x-1) + 2*img_f(ind_y-1,ind_x) + 1*img_f(ind_y-1,ind_x+1) + ...
                        0*img_f(ind_y  ,ind_x-1) - 0*img_f(ind_y  ,ind_x) + 0*img_f(ind_y  ,ind_x+1) + ...
                        -1*img_f(ind_y+1,ind_x-1) - 2*img_f(ind_y+1,ind_x) - 1*img_f(ind_y+1,ind_x+1) );
                    
                    % Calcul de l'angle du gradient
                    theta(ind_y, ind_x) = (180/pi)*atan2(double(G_y(ind_y, ind_x)), double(G_x(ind_y, ind_x)));
                    
                    % Arrondi de l'angle pour obtenir une direction par palier de 45°
                    if (theta(ind_y, ind_x)>=0-22.5 && theta(ind_y, ind_x)<0+22.5) ...
                            || (theta(ind_y, ind_x)>=180-22.5 && theta(ind_y, ind_x)<-180+22.5)
                        theta_p(ind_y, ind_x) = 0;
                        
                    elseif (theta(ind_y, ind_x)>=45-22.5 && theta(ind_y, ind_x)<45+22.5) ...
                            || (theta(ind_y, ind_x)>=-135-22.5 && theta(ind_y, ind_x)<-135+22.5)
                        theta_p(ind_y, ind_x) = 45;
                        
                    elseif (theta(ind_y, ind_x)>=90-22.5 && theta(ind_y, ind_x)<90+22.5) ...
                            || (theta(ind_y, ind_x)>=-90-22.5 && theta(ind_y, ind_x)<-90+22.5)
                        theta_p(ind_y, ind_x) = 90;
                        
                    elseif (theta(ind_y, ind_x)>=135-22.5 && theta(ind_y, ind_x)<135+22.5) ...
                            || (theta(ind_y, ind_x)>=-45-22.5 && theta(ind_y, ind_x)<-45+22.5)
                        theta_p(ind_y, ind_x) = 135;
                        
                    end
                    
                    % Module de G_x
                    if G_x(ind_y, ind_x) < 0
                        G_x(ind_y, ind_x) = -G_x(ind_y, ind_x);
                    end
                    
                    % Module de G_y
                    if G_y(ind_y, ind_x) < 0
                        G_y(ind_y, ind_x) = -G_y(ind_y, ind_x);
                    end
                    
                    % Calcul du gradient global
                    G(ind_y, ind_x) = G_x(ind_y, ind_x) + G_y(ind_y, ind_x);
                    
                    %G(ind_y, ind_x) = sqrt(double(G_y(ind_y, ind_x)^2)+double(G_x(ind_y, ind_x)^2));
                    
                end
            end
            
            % Recherche des maximum locaux (Thinning)
            G_max = G;  % Recopie pour Debug
            for ind_y = 2 : H-1
                for ind_x = 2 : W-1
                    if theta_p(ind_y, ind_x) == 0
                        if G(ind_y, ind_x)<G(ind_y, ind_x-1) || G(ind_y, ind_x)<G(ind_y, ind_x+1)
                            G_max(ind_y, ind_x) = 0;
                        end
                        
                    elseif theta_p(ind_y, ind_x) == 45
                        if G(ind_y, ind_x)<G(ind_y-1, ind_x-1) || G(ind_y, ind_x)<G(ind_y+1, ind_x+1)
                            G_max(ind_y, ind_x) = 0;
                        end
                        
                    elseif theta_p(ind_y, ind_x) == 90
                        if G(ind_y, ind_x)<G(ind_y-1, ind_x) || G(ind_y, ind_x)<G(ind_y+1, ind_x)
                            G_max(ind_y, ind_x) = 0;
                        end
                        
                    elseif theta_p(ind_y, ind_x) == 135
                        if G(ind_y, ind_x)<G(ind_y+1, ind_x-1) || G(ind_y, ind_x)<G(ind_y-1, ind_x+1)
                            G_max(ind_y, ind_x) = 0;
                        end
                        
                    end
                    
                end
            end
            
            % Seuillage pour binarisation
            max_G = max(max(G_max));
            t_low   = 0.0312*max_G;
            t_high  = 0.0781*max_G;
            
            for ind_y = 2+1 : H-2   % décallage de 2 car matrice 5x5 dans le cas incertain test2
                for ind_x = 2+1 : W-2
                    
                    if G_max(ind_y, ind_x) < t_low
                        G_bin(ind_y, ind_x) = 0;
                        
                    elseif G_max(ind_y, ind_x) >= t_high
                        G_bin(ind_y, ind_x) = 1;
                        
                    else
                        % Recherche de valeur sup. à t_high dans ses 3x3 voisins
                        if G_max(ind_y-1, ind_x-1)>=t_high || G_max(ind_y-1, ind_x)>=t_high || G_max(ind_y-1, ind_x+1)>=t_high || ...
                                G_max(ind_y  , ind_x-1)>=t_high ||                                  G_max(ind_y  , ind_x+1)>=t_high || ...
                                G_max(ind_y+1, ind_x-1)>=t_high || G_max(ind_y+1, ind_x)>=t_high || G_max(ind_y+1, ind_x+1)>=t_high
                            
                            G_bin(ind_y, ind_x) = 1;
                            
                        elseif (G_max(ind_y-1, ind_x-1)>=t_low && G_max(ind_y-1, ind_x-1)<t_high) || ...
                                (G_max(ind_y-1, ind_x  )>=t_low && G_max(ind_y-1, ind_x  )<t_high) || ...
                                (G_max(ind_y-1, ind_x+1)>=t_low && G_max(ind_y-1, ind_x+1)<t_high) || ...
                                (G_max(ind_y  , ind_x-1)>=t_low && G_max(ind_y  , ind_x-1)<t_high) || ...
                                (G_max(ind_y  , ind_x+1)>=t_low && G_max(ind_y  , ind_x+1)<t_high) || ...
                                (G_max(ind_y+1, ind_x-1)>=t_low && G_max(ind_y+1, ind_x-1)<t_high) || ...
                                (G_max(ind_y+1, ind_x  )>=t_low && G_max(ind_y+1, ind_x  )<t_high) || ...
                                (G_max(ind_y+1, ind_x+1)>=t_low && G_max(ind_y+1, ind_x+1)<t_high)
                            
                            % Si on est en zone incertaine ( t_low <= G < t_high)
                            % On test les voisins avec une matrice 5x5 (les voisins 3x3
                            % sont déjà testé.
                            if G_max(ind_y-2, ind_x-2)>=t_high || G_max(ind_y-2, ind_x-1)>=t_high || G_max(ind_y-2, ind_x)>=t_high || G_max(ind_y-2, ind_x+1)>=t_high || G_max(ind_y-2, ind_x+2)>=t_high || ...
                                    G_max(ind_y-1, ind_x-2)>=t_high ||                                                                                                        G_max(ind_y-1, ind_x+2)>=t_high || ...
                                    G_max(ind_y  , ind_x-2)>=t_high ||                                                                                                        G_max(ind_y  , ind_x+2)>=t_high || ...
                                    G_max(ind_y+1, ind_x-2)>=t_high ||                                                                                                        G_max(ind_y+1, ind_x+2)>=t_high || ...
                                    G_max(ind_y+2, ind_x-2)>=t_high || G_max(ind_y+2, ind_x-1)>=t_high || G_max(ind_y+2, ind_x)>=t_high || G_max(ind_y+2, ind_x+1)>=t_high || G_max(ind_y+2, ind_x+2)>=t_high
                                
                                G_bin(ind_y, ind_x) = 1;
                                
                            else
                                % Si aucun des 5x5 voisins n'est supérieur à t_high il
                                % ne s'agit pas d'un contour
                                G_bin(ind_y, ind_x) = 0;
                                
                            end
                            
                        else
                            % Si on est inférieur à t_low il ne sagit pas d'un contour
                            G_bin(ind_y, ind_x) = 0;
                            
                        end
                        
                    end
                end
            end
            %% Affichage Canny
            imshow(G_bin, [0 1], 'parent', app.ImageAxes);
            fileID=fopen('C:\Users\user\Desktop\pfa\MyMatrix.txt','w');
            fprintf(fileID,'%x',G_bin);
            fclose(fileID);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            fig=app.ImageAxes;
            [file,path,index]=uiputfile('*.png');
            if index==1
                myFile=fullfile(path,file);
                save(myFile,"fig");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Histograms';
            app.Panel = uipanel(app.UIFigure);
            app.ImageAxes = uiaxes(app.Panel);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.LoadButton = uibutton(app.Panel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load Custom Image';
            app.ImagesLabel = uilabel(app.Panel);
            app.ImagesLabel.Text = 'Images';
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {'01', '02', '03', '04', '05', '06', '07', '08', '09', '10'};
            app.DropDown.ItemsData = {'01.png', '02.png', '03.png', '04.png', '05.png', '06.png', '07.png', '08.png', '09.png', '10.png'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '01.png';
            app.ShowButton = uibutton(app.Panel, 'push');
            app.ShowButton.ButtonPushedFcn = createCallbackFcn(app, @ShowButtonPushed, true);
            app.ShowButton.Text = 'Show';
            app.EdgeDetectionLabel = uilabel(app.Panel);
            app.EdgeDetectionLabel.Text = 'Edge Detection';
            app.SaveButton = uibutton(app.Panel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EdgeDetection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end